classdef UTM_BAK < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileNameEditField               matlab.ui.control.EditField
        FileNameEditFieldLabel          matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        L_0EditField                    matlab.ui.control.NumericEditField
        L_0EditFieldLabel               matlab.ui.control.Label
        TareButton_2                    matlab.ui.control.Button
        StopcameraButton                matlab.ui.control.Button
        StartcameraButton               matlab.ui.control.Button
        IncrementalMovePanel            matlab.ui.container.Panel
        MoveDownButton                  matlab.ui.control.Button
        MoveEditField                   matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        mmLabel_2                       matlab.ui.control.Label
        MoveUpButton                    matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        SpeedLabel                      matlab.ui.control.Label
        SaveDataButton                  matlab.ui.control.Button
        StatusBox                       matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        DirectionKnob                   matlab.ui.control.DiscreteKnob
        DirectionKnobLabel              matlab.ui.control.Label
        TareLocationButton              matlab.ui.control.Button
        StatusLamp                      matlab.ui.control.Lamp
        Label_2                         matlab.ui.control.Label
        EmergencySTOPButton             matlab.ui.control.Button
        MotorsSwitch                    matlab.ui.control.RockerSwitch
        MotorsSwitchLabel               matlab.ui.control.Label
        SetRPMEditField                 matlab.ui.control.NumericEditField
        SetRPMEditFieldLabel            matlab.ui.control.Label
        RPMSwitch                       matlab.ui.control.Switch
        RPMGauge                        matlab.ui.control.Gauge
        SwitchLabel                     matlab.ui.control.Label
        VelocitySwitch                  matlab.ui.control.Switch
        VelocitySwitchLabel             matlab.ui.control.Label
        PositionSwitch                  matlab.ui.control.Switch
        PositionSwitchLabel             matlab.ui.control.Label
        LoadCellSwitch                  matlab.ui.control.Switch
        LoadCellSwitchLabel             matlab.ui.control.Label
        deltaGauge                      matlab.ui.control.LinearGauge
        delta0000mmLabel                matlab.ui.control.Label
        setRPMKnob                      matlab.ui.control.Knob
        KnobLabel                       matlab.ui.control.Label
        ConnectionSwitch                matlab.ui.control.Switch
        ConnectionSwitchLabel           matlab.ui.control.Label
        AvailableCOMportsDropDown       matlab.ui.control.DropDown
        AvailableCOMportsDropDownLabel  matlab.ui.control.Label
        ScanforCOMportsButton           matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        StressStrainTab                 matlab.ui.container.Tab
        mmLabel_3                       matlab.ui.control.Label
        L_0EditField_2                  matlab.ui.control.NumericEditField
        L_0Label                        matlab.ui.control.Label
        MarkersStressPlotCheckBox       matlab.ui.control.CheckBox
        TareStressPlotButton            matlab.ui.control.Button
        mm2Label                        matlab.ui.control.Label
        AreaEditField                   matlab.ui.control.NumericEditField
        AreaEditFieldLabel              matlab.ui.control.Label
        StressCropButton                matlab.ui.control.Button
        StressRangeSlider               matlab.ui.control.RangeSlider
        ShowrangeSliderLabel            matlab.ui.control.Label
        ClearStressPlotButton           matlab.ui.control.Button
        ImagePlot                       matlab.ui.control.UIAxes
        StressPlot                      matlab.ui.control.UIAxes
        StressStrainTab_2               matlab.ui.container.Tab
        mmLabel_4                       matlab.ui.control.Label
        L_0EditField_3                  matlab.ui.control.NumericEditField
        L_0EditField_3Label             matlab.ui.control.Label
        MarkersStressPlotCheckBox_2     matlab.ui.control.CheckBox
        TareStressPlotButton_2          matlab.ui.control.Button
        mm2Label_2                      matlab.ui.control.Label
        AreaEditField_2                 matlab.ui.control.NumericEditField
        AreaEditField_2Label            matlab.ui.control.Label
        StressCropButton_2              matlab.ui.control.Button
        StressRangeSlider_2             matlab.ui.control.RangeSlider
        ShowrangeSliderLabel_2          matlab.ui.control.Label
        ClearStressPlotButton_2         matlab.ui.control.Button
        ImagePlot_2                     matlab.ui.control.UIAxes
        CurvePlot_2                     matlab.ui.control.UIAxes
        ConsoleTab                      matlab.ui.container.Tab
        BaudRateDropDown                matlab.ui.control.DropDown
        BaudRateDropDownLabel           matlab.ui.control.Label
        CommandEditField                matlab.ui.control.EditField
        CommandEditFieldLabel           matlab.ui.control.Label
        ClearConsoleButton              matlab.ui.control.Button
        TimeStampButton                 matlab.ui.control.StateButton
        AutoscrollButton                matlab.ui.control.StateButton
        LineEndingDropDown              matlab.ui.control.DropDown
        SendButton                      matlab.ui.control.Button
        ConsoleTextArea                 matlab.ui.control.TextArea
        ConsoleTextAreaLabel            matlab.ui.control.Label
        LoadPlotTab                     matlab.ui.container.Tab
        MarkersCheckBox                 matlab.ui.control.CheckBox
        CalibrationPanel                matlab.ui.container.Panel
        ScaleEditField                  matlab.ui.control.NumericEditField
        ScaleEditFieldLabel             matlab.ui.control.Label
        OffsetEditField                 matlab.ui.control.NumericEditField
        OffsetEditFieldLabel            matlab.ui.control.Label
        CalibrateButton                 matlab.ui.control.Button
        WeightingEditField              matlab.ui.control.NumericEditField
        WeightingLabel                  matlab.ui.control.Label
        CropLoadButton                  matlab.ui.control.Button
        CropLoadSlider                  matlab.ui.control.RangeSlider
        ClearLoadPlotButton             matlab.ui.control.Button
        TareButton                      matlab.ui.control.Button
        LoadPlot                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        consoleText % Description
        device % Description
        data
        hExternalFigure
        hExternalPlot
        dataCounter = 0;
        maxDataCount = 500;
        hLoadPlot % Description
        hStressPlot
        maxConsoleLines = 500;
        connectionEstablished = 0;
        previousConnectionState = 0;
        positionRaw = 0
        position = 0
        position0 = 0;
        Timer1
        Timer2
        hLimitPlots
        hLimitPlotsStress
        vid
        src
        image
        imagePos
    end
    
    methods (Access = public)
        
        function AppendToConsole(app, line)
            if isempty(app.ConsoleTextArea.Value{1})
                app.ConsoleTextArea.Value{1} = convertStringsToChars(line);
            else
                app.ConsoleTextArea.Value{end+1} = convertStringsToChars(line);
            end
            

            if app.AutoscrollButton.Value
                scroll(app.ConsoleTextArea,"bottom");
            end
        end

        function ScanForCOMPorts(app)
            ports = serialportlist;
            nPorts = length(ports);
            if nPorts == 0
                msg = "No COM ports detected on system! Please check the USB cable.";
                app.AppendToConsole(msg);
                app.TabGroup.SelectedTab = app.ConsoleTab;
                msgbox(msg,"Error","error")
            end
            for iPort = 1:nPorts
                port = ports(iPort);
                app.AvailableCOMportsDropDown.Items{iPort} = convertStringsToChars(port);
                app.AppendToConsole(['Found port: ', convertStringsToChars(port)]);
            end
        end

        function GetSerialData(app, src, ~)
            
            raw = fgets(src);
            raw = strtrim(raw);
            
            timestamp = datetime('now','TimeZone','local','Format','HH:mm:ss.SSS');
            line = [];
            if app.TimeStampButton.Value
                time = datestr(timestamp,'HH:MM:SS.FFF');
                line = [line, time, ' -> '];
            end
            line = [line, raw];
            
            
            if contains(line, 'Welcome')
                app.connectionEstablished = 1;
                return
            end
            
            if contains(line, 'Velocity: ')
                str = extractAfter(line, 'Velocity: ');
                strArray = strsplit(str, '\t');
                val = str2double(strArray);
                vel = val(2);
                
                if strcmpi(app.RPMSwitch.Value, "RPM")
                    app.RPMGauge.Value = vel;
                    app.SpeedLabel.Text = sprintf("Speed: %0.0f RPM",vel);
                else
                    vx = vel/60/20*5;
                    app.RPMGauge.Value = vx;
                    app.SpeedLabel.Text = sprintf("Speed: %0.2f mm/s", vx);
                end
                return
            end

            if contains(line, 'Total Angle: ')
                str = extractAfter(line, 'Total Angle: ');
                strArray = strsplit(str, '\t');
                val = str2double(strArray);
                val = -val; %Flip sign, up is down, down is up
                pos = val*360/4096; % degrees
                pos = pos/360;   % rounds
                pos = pos/20;    % rounds on screw
                pos = pos*5;      % mm
                
                app.positionRaw = pos;

                app.position = app.positionRaw - app.position0;

                app.delta0000mmLabel.Text = ['$\delta=', sprintf('%0.4f',app.position), '$ mm'];
                app.delta0000mmLabel.Interpreter = "latex";
                app.deltaGauge.Value = app.position;

                return
            end
            
            if contains(line, 'Command')
                return
            end

            app.AppendToConsole(line);

            if length(app.ConsoleTextArea.Value) >= app.maxConsoleLines
                app.ClearConsoleButtonPushed()
            end
            
            strArray = strsplit(line, '\t');
            force = str2double(strArray);
            app.ProcessLoadData(force);

            
        end

        function ProcessLoadData(app, force)
            
            if isempty(force); return; end
            % force
            
            app.dataCounter = app.dataCounter+1;
            
            timestamp = datetime('now','TimeZone','local','Format','HH:mm:ss.SSS');
            app.data{app.dataCounter, 1} = timestamp;
            app.data{app.dataCounter, 2} = force;

            F = -force * app.ScaleEditField.Value - app.OffsetEditField.Value;
            app.data{app.dataCounter, 3} = F;
            app.data{app.dataCounter, 4} = app.position;
            
            
            y = [app.data{:,3}];
            x = [app.data{:,1}];

            windowDuration = 1;
            samplingRate = 10;
            windowSize = round(windowDuration * samplingRate);
            ym = movmean(y, windowSize);
            set(app.hLoadPlot,'XData',x,'YData',ym);
            if length(app.data) >= app.maxDataCount
                xlim(app.LoadPlot, 'auto');
            end
            
            A = app.AreaEditField.Value;
            stress = F/A;

            delta = app.position;
            L0 = app.L_0EditField.Value;
            epsilon = delta/L0;
            
            
            app.data{app.dataCounter, 5} = epsilon;
            app.data{app.dataCounter, 6} = stress;


            set(app.hStressPlot, 'XData', [app.data{:,5}], 'YData', [app.data{:,6}])
            % if length(app.data) >= app.maxDataCount
            %     xlim(app.hStressPlot, 'auto');
            % end


        end
        
        function RescaleTime(app)
            freq = 10;
            timeOffset = app.maxDataCount / freq
            currentTime = datetime('now','TimeZone','local','Format','HH:mm:ss.SSS');
            futureTime = currentTime + seconds(timeOffset)
            xlim(app.LoadPlot, [currentTime, futureTime]);
        end

        function RedrawLoadPlot(app)
            delete(app.hLoadPlot)
            currentTime = datetime('now','TimeZone','local','Format','HH:mm:ss.SSS');
            app.hLoadPlot = plot(app.LoadPlot, currentTime, NaN, '-b.');
            app.RescaleTime();
        end

        function ClearLoadData(app)
            app.data = {};
            app.dataCounter = 0;
        end
        
        function Timer2CallbackFcn(app, ~, ~)
            try
                if strcmpi(app.PositionSwitch.Value, 'On')
                    app.device.writeline("GetTotalAngle");
                end
                if strcmpi(app.VelocitySwitch.Value, 'On')
                    app.device.writeline("GetVelocity");
                end
            end
        end

        function Timer1CallbackFcn(app, ~, ~)
            % Checking connection every 0.5 seconds.
            if isobject(app.device) && isvalid(app.device)
                try getpinstatus(app.device); 
                catch
                    app.connectionEstablished = 0;
                end
            else
                app.connectionEstablished = 0;
            end
            
            if app.connectionEstablished == app.previousConnectionState; return; end
            % Ensures that this below only gets run ONCE!
            app.previousConnectionState = app.connectionEstablished;
            
            if app.connectionEstablished
                app.OnSerialConnected();
            else
                app.OnSerialDisconnected();
            end
        end

        function OnSerialConnected(app)
            app.StatusBox.Value = "Status: Connected to UTM.";
            app.StatusLamp.Color = 'g';
            app.StatusLamp.Tooltip = "Connected to UTM";
            disp("Connected to UTM")
            app.LoadCellSwitch.Enable = "On";
            app.PositionSwitch.Enable = "On";
            app.VelocitySwitch.Enable = "On";
            app.MotorsSwitch.Enable = "On";
            app.RPMSwitch.Enable = "On";
            app.SetRPMEditField.Enable = "On";
            app.setRPMKnob.Enable = "On";
            app.DirectionKnob.Enable = "On";
            app.BaudRateDropDown.Enable = "Off";
            app.LineEndingDropDown.Enable = "Off";
            app.AvailableCOMportsDropDown.Enable = "Off";
            app.EmergencySTOPButton.Enable = "On";
            app.RPMGauge.Enable = "On";
            app.deltaGauge.Enable = "On";
            app.TareLocationButton.Enable = "On";
            app.IncrementalMovePanel.Enable = "On";

            app.MotorsSwitch.Value = "Off";
            app.setRPMKnob.Value = 0;
            app.SetRPMEditField.Value = 0;
            app.DirectionKnob.Value = "Stop";
            
            % This helps with getting weird noisy velocities
            app.device.writeline("Enable");
            pause(0.1);
            app.device.writeline("Disable");
        end

        function OnSerialDisconnected(app)
            app.StatusBox.Value = "Status: Not connected to UTM.";
            app.StatusLamp.Color = 'k';
            app.StatusLamp.Tooltip = "Not connected to UTM";
            disp("Disconnected from UTM")
            app.LoadCellSwitch.Enable = "Off";
            app.PositionSwitch.Enable = "Off";
            app.VelocitySwitch.Enable = "Off";
            app.MotorsSwitch.Enable = "Off";
            app.RPMSwitch.Enable = "Off";
            app.SetRPMEditField.Enable = "Off";
            app.setRPMKnob.Enable = "Off";
            app.DirectionKnob.Enable = "Off";
            app.BaudRateDropDown.Enable = "On";
            app.LineEndingDropDown.Enable = "On";
            app.AvailableCOMportsDropDown.Enable = "On";
            app.EmergencySTOPButton.Enable = "Off";
            app.RPMGauge.Enable = "Off";
            app.deltaGauge.Enable = "Off";
            app.TareLocationButton.Enable = "Off";
            app.IncrementalMovePanel.Enable = "Off";

            app.MotorsSwitch.Value = "Off";
            app.setRPMKnob.Value = 0;
            app.SetRPMEditField.Value = 0;
            app.DirectionKnob.Value = "Stop";
        end
        
        function SetSpeed(app)
            if strcmpi(app.RPMSwitch.Value, 'RPM')
                value = round(app.setRPMKnob.Value);
                app.SetRPMEditField.Value = value;
                rpmVal = value;
            else
                value = app.setRPMKnob.Value;
                app.SetRPMEditField.Value = value;
                rpmVal = round(value*60*20/5);
            end
            app.device.writeline(sprintf("SetSpeed %d", rpmVal*10));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.ScanForCOMPorts();
            app.data = {};
            
            % Timer checking connectivity
            app.Timer1 = timer;
            app.Timer1.Period = 0.5;
            app.Timer1.ExecutionMode = 'fixedRate';
            app.Timer1.TimerFcn = @(~,~)app.Timer1CallbackFcn();
            start(app.Timer1);
            
            % Timer polling position and velocity
            app.Timer2 = timer;
            app.Timer2.Period = 0.1;
            app.Timer2.ExecutionMode = 'fixedRate';
            app.Timer2.TimerFcn = @(~,~)app.Timer2CallbackFcn();
            start(app.Timer2);
            
            currentTime = datetime('now','TimeZone','local','Format','HH:mm:ss.SSS');
            app.hLoadPlot = plot(app.LoadPlot, currentTime, NaN, '-b.');
            hold(app.LoadPlot,'On');
            app.hLimitPlots = plot(app.LoadPlot,currentTime, NaN);

            app.hStressPlot = plot(app.StressPlot, NaN, NaN, '-b.');
            hold(app.StressPlot,'On');
            app.hLimitPlotsStress = plot(app.StressPlot, NaN, NaN);

            app.OnSerialDisconnected();

            app.imagePos = app.ImagePlot.Position;
            
            
        end

        % Button pushed function: ScanforCOMportsButton
        function ScanforCOMportsButtonPushed(app, event)
            app.ScanForCOMPorts();
            app.data = {};
        end

        % Button pushed function: ClearConsoleButton
        function ClearConsoleButtonPushed(app, event)
            app.ConsoleTextArea.Value = {''};
        end

        % Value changed function: ConnectionSwitch
        function ConnectionSwitchValueChanged(app, event)
            value = app.ConnectionSwitch.Value;
            app.TabGroup.SelectedTab = app.ConsoleTab;
            if strcmpi(value, 'on')
                app.AppendToConsole(['Connecting on COM port: ', convertStringsToChars(app.AvailableCOMportsDropDown.Value)])
                port = app.AvailableCOMportsDropDown.Value;
                baudRate = str2double(app.BaudRateDropDown.Value);
                
                try app.device = serialport(port, baudRate,"Timeout",5);
                catch
                    app.AppendToConsole('Cannot connect to port, verify that port exists and is open!')
                    return
                end
                switch app.LineEndingDropDown.Value
                    case "New Line"
                        terminator = 'LF';
                    case "Carriage Return"
                        terminator = 'CR';
                    case "Both NL & CR"
                        terminator = 'CR/LF';
                end
                app.device.configureTerminator(terminator);
                app.device.configureCallback("terminator", @app.GetSerialData);


            else
                try 
                    app.device.delete();
                    app.AppendToConsole('Connection closed');
                end
                app.connectionEstablished = 0;
                app.OnSerialDisconnected();
            end
        end

        % Value changed function: MotorsSwitch
        function MotorsSwitchValueChanged(app, event)
            value = app.MotorsSwitch.Value;
            if strcmpi(value, 'on')
                app.device.writeline("Enable");
            else
                app.device.writeline("Disable");
            end
        end

        % Value changed function: CommandEditField
        function CommandEditFieldValueChanged(app, event)
            value = app.CommandEditField.Value;
            if ~isempty(value)
                app.device.writeline(value);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            selection = uiconfirm(app.UIFigure, ...
                'Are you sure you want to close the app?', ...
                'Close Confirmation', ...
                'Options', {'Yes', 'No'}, ...
                'DefaultOption', 'No', ...
                'Icon', 'warning');
            if strcmp(selection, 'No'); return; end

            try app.device.delete(); end
            try stop(app.Timer1); end
            try delete(app.Timer1); end
            try stop(app.Timer2); end
            try delete(app.Timer2); end
            disp('Goodbye')
            delete(app)
            
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            value = app.CommandEditField.Value;
            app.device.writeline(value);
        end

        % Button pushed function: EmergencySTOPButton
        function EmergencySTOPButtonPushed(app, event)
            app.device.writeline("EStop");
            app.DirectionKnob.Value = "Stop";
        end

        % Value changed function: setRPMKnob
        function setRPMKnobValueChanged(app, event)
            
            app.SetSpeed();
          
        end

        % Value changed function: SetRPMEditField
        function SetRPMEditFieldValueChanged(app, event)
            if strcmpi(app.RPMSwitch.Value, 'RPM')
                value = round(app.SetRPMEditField.Value);
                app.SetRPMEditField.Value = value;
                app.setRPMKnob.Value = value;
                rpmVal = value;
            else
                value = app.SetRPMEditField.Value;
                rpmVal = value*60*20/5;
            end
            app.device.writeline(sprintf("SetSpeed %d", rpmVal*10));
        end

        % Value changed function: DirectionKnob
        function DirectionKnobValueChanged(app, event)
            
            value = app.DirectionKnob.Value;
            if strcmpi(value, 'Down')
                app.SetSpeed();
                app.device.writeline("Down");
            elseif strcmpi(value, 'Up')
                app.SetSpeed();
                app.device.writeline("Up");
            else 
                app.device.writeline("Stop");
            end
        end

        % Value changed function: RPMSwitch
        function RPMSwitchValueChanged(app, event)
            value = app.RPMSwitch.Value;            
            if strcmpi(value, 'RPM')
                val = app.SetRPMEditField.Value;
                val = val*60*20/5; %Convert to rpm
                
                app.setRPMKnob.Limits = [0, 240];
                app.setRPMKnob.MajorTicks = linspace(0, 240, 9);
                app.SetRPMEditField.Limits = [0, 240];
                
                app.SetRPMEditField.Value = val;
                app.setRPMKnob.Value = val;

                app.SetRPMEditFieldLabel.Text = "Set RPM:";
                app.RPMGauge.Limits = [-240, 240];
            else
                val = app.SetRPMEditField.Value;
                val = val/60/20*5; % Convert to mm/s
                
                app.setRPMKnob.Limits = [0, 1];
                app.setRPMKnob.MajorTicks = [0, 0.1, 0.2, 0.3, 0.4, 0.5, 0.6, 0.7, 0.8, 0.9, 1];
                app.SetRPMEditField.Limits = [0, 1];

                app.SetRPMEditField.Value = val;
                app.setRPMKnob.Value = val;

                app.SetRPMEditFieldLabel.Text = "Set mm/s:";
                app.RPMGauge.Limits = [-1, 1];
            end
            
        end

        % Value changed function: LoadCellSwitch
        function LoadCellSwitchValueChanged(app, event)
            value = app.LoadCellSwitch.Value;
            
            if strcmpi(value, 'On')
                app.device.writeline("LoadCellOn");
                % app.RedrawLoadPlot();
            else
                app.device.writeline("LoadCellOff");
                currentTime = datetime('now','TimeZone','local','Format','HH:mm:ss.SSS');
                app.dataCounter = app.dataCounter +1;
                app.data{app.dataCounter, 1} = currentTime;
                app.data{app.dataCounter, 2} = NaN;
                app.data{app.dataCounter, 3} = NaN;
                % app.RedrawLoadPlot();
            end
        end

        % Value changed function: PositionSwitch
        function PositionSwitchValueChanged(app, event)
            value = app.PositionSwitch.Value;
            % if strcmpi(value, 'On')
            %     app.device.writeline("AngleOn");
            % else
            %     app.device.writeline("AngleOff");
            % end
        end

        % Value changed function: VelocitySwitch
        function VelocitySwitchValueChanged(app, event)
            value = app.VelocitySwitch.Value;
            % if strcmpi(value, 'On')
            %     app.device.writeline("VelocityOn");
            % else
            %     app.device.writeline("VelocityOff");
            % end
        end

        % Button pushed function: ClearLoadPlotButton
        function ClearLoadPlotButtonPushed(app, event)
            app.ClearLoadData()
            % app.RedrawLoadPlot();
        end

        % Button pushed function: TareButton
        function TareButtonPushed(app, event)
            
            loadCellData = [app.data{:,3}];
            if length(loadCellData) >= 50
                offset = mean(loadCellData(end-50:end));
            else
                offset = mean(loadCellData);
            end
            
            app.OffsetEditField.Value = app.OffsetEditField.Value + offset;
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            hmsgbox = msgbox("Input the weight in grams to use in the calibration. Press OK when done.");
            uiwait(hmsgbox)
            
            weight = app.WeightingEditField.Value


            hmsgbox = msgbox("Remove any weight from the loadcell. Press OK when done.");
            uiwait(hmsgbox)

            app.data = {};
            app.dataCounter = 0;

            app.StatusBox.Value = "Status: Gathering load cell data for 10 seconds, do not touch the loadcell...";
            pause(10);

            loadCellData = [app.data{:,2}];
            force0 = mean(loadCellData) 


            hmsgbox = msgbox("Put the known weight on the loadcell.  Press OK when done.");
            uiwait(hmsgbox)

            app.data = {};
            app.dataCounter = 0;

            app.StatusBox.Value = "Status: Gathering load cell data for 10 seconds, do not touch the loadcell...";
            pause(10);

            
            loadCellData = [app.data{:,2}];
            force1 = mean(loadCellData)
            weight = app.WeightingEditField.Value
            
            deltaForce = force1-force0

            scale = (weight/1000*9.82) / (deltaForce)
            

            offset = force0*scale


            app.OffsetEditField.Value = offset;
            app.ScaleEditField.Value = scale;
            
            app.StatusBox.Value = "Status: Load cell is calibrated.";


        end

        % Button pushed function: CropLoadButton
        function CropLoadButtonPushed(app, event)
            % hFig = figure; hAx = gca();
            % hPlot = plot(hAx, app.hLoadPlot.XData, app.hLoadPlot.YData);
            x0 = app.CropLoadSlider.Value(1);
            x1 = app.CropLoadSlider.Value(2);

            np = length(app.hLoadPlot.XData);

            xlow = floor(x0/100 * np); if xlow == 0; xlow = 1; end
            xhigh = ceil(x1/100 * np);


            XD = app.hLoadPlot.XData();
            YD = app.hLoadPlot.YData();
            
            % app.data

            % num2cell(XD)
            
            app.data = [num2cell([XD(xlow:xhigh)])', num2cell([YD(xlow:xhigh)])'];
            

            [app.data{:,1}]
            set(app.hLoadPlot, 'XData', [app.data{:,1}],...
                               'YData', [app.data{:,2}])


            app.CropLoadSlider.Value = [0,100];

        end

        % Value changing function: CropLoadSlider
        function CropLoadSliderValueChanging(app, event)
            
            set(app.hLimitPlots, 'Visible', 'On')
            changingValue = event.Value;
            
            ymin = min(app.hLoadPlot.YData);
            ymax = max(app.hLoadPlot.YData);

            x0 = changingValue(1);
            x1 = changingValue(2);


            np = length(app.hLoadPlot.XData);

            xlow = floor(x0/100 * np); if xlow == 0; xlow = 1; end
            xhigh = ceil(x1/100 * np);
            
            XD = app.hLoadPlot.XData()';

            set(app.hLimitPlots, 'XData', [XD(xlow), XD(xlow), XD(xlow), XD(xhigh), XD(xhigh)],...
                                 'YData', [ymin, ymax, NaN, ymin, ymax],...
                                 'Color','k', 'linestyle','-');
        end

        % Value changed function: CropLoadSlider
        function CropLoadSliderValueChanged(app, event)
            value = app.CropLoadSlider.Value;
            set(app.hLimitPlots, 'Visible', 'Off')
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            % force = [app.data{:,3}];
            % delta = [app.data{:,4}];
            % epsilon = [app.data{:,5}];
            % stress = [app.data{:,6}];
            % A = app.AreaEditField.Value;
            % L0 = app.L_0EditField.Value;
            
            currentTime = datetime('now','TimeZone','local','Format','yyyyMMdd_HHmmss');
            fileName = app.FileNameEditField.Value;
            if isempty(fileName)
                fileName = "UTM_Data";
            end
            fileName = sprintf("%s_%s.mat",fileName, string(currentTime))

            Data = app.data;
            save(fileName, "Data")

        end

        % Value changing function: setRPMKnob
        function setRPMKnobValueChanging(app, event)
            changingValue = event.Value;
            if strcmpi(app.RPMSwitch.Value, 'RPM')
                value = round(changingValue);
                app.SetRPMEditField.Value = value;
            else
                value = changingValue;
                % rpmVal = round(value*60*20/5)
                app.SetRPMEditField.Value = value;
                
            end
            % app.device.writeline(sprintf("SetSpeed %d", rpmVal*10));
        end

        % Button pushed function: TareLocationButton
        function TareLocationButtonPushed(app, event)
            app.position0 = app.positionRaw;
            % app.position0
        end

        % Value changed function: MarkersCheckBox
        function MarkersCheckBoxValueChanged(app, event)
            value = app.MarkersCheckBox.Value;
            if value
                app.hLoadPlot.Marker = '.';
            else
                app.hLoadPlot.Marker = 'none';
            end
        end

        % Button pushed function: MoveUpButton
        function MoveUpButtonPushed(app, event)
            distance = -abs(app.MoveEditField.Value);
            
            nStepsPerRound = 200;
            microStepping = 8;
            pitch = 5;
            gearRatio = 20;

            steps = round(nStepsPerRound*microStepping*gearRatio*distance/pitch);
            
            app.device.writeline(sprintf("MoveSteps %d",steps));

            app.DirectionKnob.Value = "Up";
        end

        % Button pushed function: MoveDownButton
        function MoveDownButtonPushed(app, event)
            distance = abs(app.MoveEditField.Value);

            nStepsPerRound = 200;
            microStepping = 8;
            pitch = 5;
            gearRatio = 20;

            steps = round(nStepsPerRound*microStepping*gearRatio*distance/pitch);
            app.device.writeline(sprintf("MoveSteps %d",steps));
            app.DirectionKnob.Value = "Down";
        end

        % Button pushed function: StartcameraButton
        function StartcameraButtonPushed(app, event)
            imaqreset; % Resets the Image Acquisition Toolbox and releases all hardware
            app.vid = videoinput("gentl", 1, "Mono8");

            app.vid.ROIPosition = [888 300 303 1756];


            app.src = getselectedsource( app.vid );
            app.src.AcquisitionFrameRate = 35.000525007875119;
            app.src.AcquisitionFrameRateEnable = "True";
            app.src.AutoFunctionProfile = "MinimizeExposureTime";
            app.src.AutoGainUpperLimit = 24;
            app.src.AutoTargetBrightness = 0.50196078431372548;
            app.src.ExposureTime = 2500;
            app.src.Gamma = 0.5;

            triggerconfig(app.vid, 'manual');
            app.vid.TriggerRepeat = Inf; % Continuous acquisition

            start(app.vid);              % Start acquisition

            frame = getsnapshot(app.vid);
            app.image = imshow(frame, "Parent", app.ImagePlot);

            % Rotate the image
            rotatedImage = rot90(app.image.CData); % Rotate the image
            app.image.CData = rotatedImage;       % Update the image in the axis


            app.ImagePlot.Position = app.imagePos;
        end

        % Button pushed function: StopcameraButton
        function StopcameraButtonPushed(app, event)
            stoppreview(app.vid)
            stop(app.vid)
            delete(app.vid)
            cla(app.ImagePlot)
        end

        % Button pushed function: TareButton_2
        function TareButton_2Pushed(app, event)
            frame = getsnapshot(app.vid);
            % diff_im =  imsubtract(frame(:,:,1), im2gray(frame));
            newframe = imbinarize(frame, 'global');

            fig=figure(1); ax = axes(fig);
            hi = imshow(newframe, "parent", ax);
            
            rotatedImage = rot90(newframe);
            app.image = imshow(rotatedImage, "Parent", app.ImagePlot);
            
            app.ImagePlot.Position = app.imagePos;
            

            pointTracker = vision.PointTracker('MaxBidirectionalError', 2);
            blobAnalysis = vision.BlobAnalysis('AreaOutputPort', false, ... % Set blob analysis handling
                'CentroidOutputPort', true, ...
                'BoundingBoxOutputPort', true', ...
                'MinimumBlobArea', 100, ...
                'MaximumBlobArea', 1000, ...
                'MaximumCount', 8);

          [centroid, bbox] = blobAnalysis(rotatedImage)
          centroid = double(centroid)

          numblobs = size(bbox, 1)

          
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % app.ImagePlot.Position = [12    14   679   141];
            frame = getsnapshot(app.vid);
            rotatedImage = rot90(frame); % Rotate the image
            app.image.CData = rotatedImage;
        end

        % Button pushed function: ClearStressPlotButton
        function ClearStressPlotButtonPushed(app, event)
            app.ClearLoadData()
        end

        % Value changed function: MarkersStressPlotCheckBox
        function MarkersStressPlotCheckBoxValueChanged(app, event)
            value = app.MarkersStressPlotCheckBox.Value;
            if value
                app.hStressPlot.Marker = '.';
            else
                app.hStressPlot.Marker = 'none';
            end
        end

        % Button pushed function: TareStressPlotButton
        function TareStressPlotButtonPushed(app, event)
            loadCellData = [app.data{:,3}];
            if length(loadCellData) >= 50
                offset = mean(loadCellData(end-50:end));
            else
                offset = mean(loadCellData);
            end
            
            app.OffsetEditField.Value = app.OffsetEditField.Value + offset;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StressStrainTab = uitab(app.TabGroup);
            app.StressStrainTab.Title = 'Stress/Strain';
            app.StressPlot = uiaxes(app.StressStrainTab);
            title(app.StressPlot, 'Stress - strain curve')
            xlabel(app.StressPlot, 'Strain [mm/mm]')
            ylabel(app.StressPlot, 'Stress [N/mm^2]')
            zlabel(app.StressPlot, 'Z')
            app.ImagePlot = uiaxes(app.StressStrainTab);
            title(app.ImagePlot, 'No blobs found in frame')
            app.ImagePlot.XTick = [];
            app.ImagePlot.YTick = [];
            app.ImagePlot.ZTick = [];
            app.ClearStressPlotButton = uibutton(app.StressStrainTab, 'push');
            app.ClearStressPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearStressPlotButtonPushed, true);
            app.ClearStressPlotButton.Text = 'Clear plot';
            app.ShowrangeSliderLabel = uilabel(app.StressStrainTab);
            app.ShowrangeSliderLabel.Text = 'Show range';
            app.StressRangeSlider = uislider(app.StressStrainTab, 'range');
            app.StressCropButton = uibutton(app.StressStrainTab, 'push');
            app.StressCropButton.Text = 'Crop';
            app.AreaEditFieldLabel = uilabel(app.StressStrainTab);
            app.AreaEditFieldLabel.Text = 'Area:';
            app.AreaEditField = uieditfield(app.StressStrainTab, 'numeric');
            app.AreaEditField.Limits = [0 1000];
            app.AreaEditField.Value = 80;
            app.mm2Label = uilabel(app.StressStrainTab);
            app.mm2Label.Interpreter = 'tex';
            app.mm2Label.Text = 'mm^2';
            app.TareStressPlotButton = uibutton(app.StressStrainTab, 'push');
            app.TareStressPlotButton.ButtonPushedFcn = createCallbackFcn(app, @TareStressPlotButtonPushed, true);
            app.TareStressPlotButton.Text = 'Tare';
            app.MarkersStressPlotCheckBox = uicheckbox(app.StressStrainTab);
            app.MarkersStressPlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @MarkersStressPlotCheckBoxValueChanged, true);
            app.MarkersStressPlotCheckBox.Text = 'Markers';
            app.MarkersStressPlotCheckBox.Value = true;
            app.L_0Label = uilabel(app.StressStrainTab);
            app.L_0Label.Interpreter = 'tex';
            app.L_0Label.Text = 'L_0:';
            app.L_0EditField_2 = uieditfield(app.StressStrainTab, 'numeric');
            app.L_0EditField_2.Value = 80;
            app.mmLabel_3 = uilabel(app.StressStrainTab);
            app.mmLabel_3.Interpreter = 'tex';
            app.mmLabel_3.Text = 'mm';
            app.StressStrainTab_2 = uitab(app.TabGroup);
            app.StressStrainTab_2.Title = 'Stress/Strain';
            app.CurvePlot_2 = uiaxes(app.StressStrainTab_2);
            title(app.CurvePlot_2, 'Stress - strain curve')
            xlabel(app.CurvePlot_2, 'Strain [mm/mm]')
            ylabel(app.CurvePlot_2, 'Stress [N/mm^2]')
            zlabel(app.CurvePlot_2, 'Z')
            app.ImagePlot_2 = uiaxes(app.StressStrainTab_2);
            title(app.ImagePlot_2, 'No blobs found in frame')
            app.ImagePlot_2.XTick = [];
            app.ImagePlot_2.YTick = [];
            app.ImagePlot_2.ZTick = [];
            app.ClearStressPlotButton_2 = uibutton(app.StressStrainTab_2, 'push');
            app.ClearStressPlotButton_2.Text = 'Clear plot';
            app.ShowrangeSliderLabel_2 = uilabel(app.StressStrainTab_2);
            app.ShowrangeSliderLabel_2.Text = 'Show range';
            app.StressRangeSlider_2 = uislider(app.StressStrainTab_2, 'range');
            app.StressCropButton_2 = uibutton(app.StressStrainTab_2, 'push');
            app.StressCropButton_2.Text = 'Crop';
            app.AreaEditField_2Label = uilabel(app.StressStrainTab_2);
            app.AreaEditField_2Label.Text = 'Area:';
            app.AreaEditField_2 = uieditfield(app.StressStrainTab_2, 'numeric');
            app.AreaEditField_2.Limits = [0 1000];
            app.AreaEditField_2.Value = 80;
            app.mm2Label_2 = uilabel(app.StressStrainTab_2);
            app.mm2Label_2.Interpreter = 'tex';
            app.mm2Label_2.Text = 'mm^2';
            app.TareStressPlotButton_2 = uibutton(app.StressStrainTab_2, 'push');
            app.TareStressPlotButton_2.Text = 'Tare';
            app.MarkersStressPlotCheckBox_2 = uicheckbox(app.StressStrainTab_2);
            app.MarkersStressPlotCheckBox_2.Text = 'Markers';
            app.MarkersStressPlotCheckBox_2.Value = true;
            app.L_0EditField_3Label = uilabel(app.StressStrainTab_2);
            app.L_0EditField_3Label.Interpreter = 'tex';
            app.L_0EditField_3Label.Text = 'L_0:';
            app.L_0EditField_3 = uieditfield(app.StressStrainTab_2, 'numeric');
            app.L_0EditField_3.Value = 80;
            app.mmLabel_4 = uilabel(app.StressStrainTab_2);
            app.mmLabel_4.Interpreter = 'tex';
            app.mmLabel_4.Text = 'mm';
            app.ConsoleTab = uitab(app.TabGroup);
            app.ConsoleTab.Title = 'Console';
            app.ConsoleTextAreaLabel = uilabel(app.ConsoleTab);
            app.ConsoleTextAreaLabel.Text = 'Console';
            app.ConsoleTextArea = uitextarea(app.ConsoleTab);
            app.SendButton = uibutton(app.ConsoleTab, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.LineEndingDropDown = uidropdown(app.ConsoleTab);
            app.LineEndingDropDown.Items = {'New Line', 'Carriage Return', 'Both NL & CR'};
            app.LineEndingDropDown.Value = 'New Line';
            app.AutoscrollButton = uibutton(app.ConsoleTab, 'state');
            app.AutoscrollButton.Text = 'Auto scroll';
            app.AutoscrollButton.Value = true;
            app.TimeStampButton = uibutton(app.ConsoleTab, 'state');
            app.TimeStampButton.Text = 'Time Stamp';
            app.ClearConsoleButton = uibutton(app.ConsoleTab, 'push');
            app.ClearConsoleButton.ButtonPushedFcn = createCallbackFcn(app, @ClearConsoleButtonPushed, true);
            app.ClearConsoleButton.Text = 'Clear Console';
            app.CommandEditFieldLabel = uilabel(app.ConsoleTab);
            app.CommandEditFieldLabel.Text = 'Command:';
            app.CommandEditField = uieditfield(app.ConsoleTab, 'text');
            app.CommandEditField.ValueChangedFcn = createCallbackFcn(app, @CommandEditFieldValueChanged, true);
            app.BaudRateDropDownLabel = uilabel(app.ConsoleTab);
            app.BaudRateDropDownLabel.Text = 'Baud Rate';
            app.BaudRateDropDown = uidropdown(app.ConsoleTab);
            app.BaudRateDropDown.Items = {'9600', '57600', '115200', '250000'};
            app.BaudRateDropDown.Value = '9600';
            app.LoadPlotTab = uitab(app.TabGroup);
            app.LoadPlotTab.Title = 'Load Plot';
            app.LoadPlot = uiaxes(app.LoadPlotTab);
            title(app.LoadPlot, 'Load')
            xlabel(app.LoadPlot, 'X')
            ylabel(app.LoadPlot, 'Y')
            zlabel(app.LoadPlot, 'Z')
            app.TareButton = uibutton(app.LoadPlotTab, 'push');
            app.TareButton.ButtonPushedFcn = createCallbackFcn(app, @TareButtonPushed, true);
            app.TareButton.Text = 'Tare';
            app.ClearLoadPlotButton = uibutton(app.LoadPlotTab, 'push');
            app.ClearLoadPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLoadPlotButtonPushed, true);
            app.ClearLoadPlotButton.Text = 'Clear plot';
            app.CropLoadSlider = uislider(app.LoadPlotTab, 'range');
            app.CropLoadSlider.ValueChangedFcn = createCallbackFcn(app, @CropLoadSliderValueChanged, true);
            app.CropLoadSlider.ValueChangingFcn = createCallbackFcn(app, @CropLoadSliderValueChanging, true);
            app.CropLoadButton = uibutton(app.LoadPlotTab, 'push');
            app.CropLoadButton.ButtonPushedFcn = createCallbackFcn(app, @CropLoadButtonPushed, true);
            app.CropLoadButton.Text = 'Crop';
            app.CalibrationPanel = uipanel(app.LoadPlotTab);
            app.CalibrationPanel.Title = 'Calibration';
            app.WeightingLabel = uilabel(app.CalibrationPanel);
            app.WeightingLabel.Text = 'Weight in g:';
            app.WeightingEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.WeightingEditField.Limits = [0 1000];
            app.CalibrateButton = uibutton(app.CalibrationPanel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.OffsetEditFieldLabel = uilabel(app.CalibrationPanel);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.OffsetEditField.Value = -24.5185;
            app.ScaleEditFieldLabel = uilabel(app.CalibrationPanel);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.ScaleEditField.Value = -0.0065;
            app.MarkersCheckBox = uicheckbox(app.LoadPlotTab);
            app.MarkersCheckBox.ValueChangedFcn = createCallbackFcn(app, @MarkersCheckBoxValueChanged, true);
            app.MarkersCheckBox.Text = 'Markers';
            app.MarkersCheckBox.Value = true;
            app.ScanforCOMportsButton = uibutton(app.UIFigure, 'push');
            app.ScanforCOMportsButton.ButtonPushedFcn = createCallbackFcn(app, @ScanforCOMportsButtonPushed, true);
            app.ScanforCOMportsButton.Text = 'Scan for COM ports';
            app.AvailableCOMportsDropDownLabel = uilabel(app.UIFigure);
            app.AvailableCOMportsDropDownLabel.Text = 'Available COM ports';
            app.AvailableCOMportsDropDown = uidropdown(app.UIFigure);
            app.AvailableCOMportsDropDown.Items = {};
            app.AvailableCOMportsDropDown.Value = {};
            app.ConnectionSwitchLabel = uilabel(app.UIFigure);
            app.ConnectionSwitchLabel.Text = 'Connection';
            app.ConnectionSwitch = uiswitch(app.UIFigure, 'slider');
            app.ConnectionSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectionSwitchValueChanged, true);
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = '';
            app.setRPMKnob = uiknob(app.UIFigure, 'continuous');
            app.setRPMKnob.Limits = [0 240];
            app.setRPMKnob.ValueChangedFcn = createCallbackFcn(app, @setRPMKnobValueChanged, true);
            app.setRPMKnob.ValueChangingFcn = createCallbackFcn(app, @setRPMKnobValueChanging, true);
            app.delta0000mmLabel = uilabel(app.UIFigure);
            app.delta0000mmLabel.Interpreter = 'latex';
            app.delta0000mmLabel.Text = '\delta \;= 0.000 mm';
            app.deltaGauge = uigauge(app.UIFigure, 'linear');
            app.deltaGauge.Limits = [-10 10];
            app.deltaGauge.MajorTicks = [-10 -5 0 5 10];
            app.LoadCellSwitchLabel = uilabel(app.UIFigure);
            app.LoadCellSwitchLabel.Text = 'Load Cell';
            app.LoadCellSwitch = uiswitch(app.UIFigure, 'slider');
            app.LoadCellSwitch.ValueChangedFcn = createCallbackFcn(app, @LoadCellSwitchValueChanged, true);
            app.PositionSwitchLabel = uilabel(app.UIFigure);
            app.PositionSwitchLabel.Text = 'Position';
            app.PositionSwitch = uiswitch(app.UIFigure, 'slider');
            app.PositionSwitch.ValueChangedFcn = createCallbackFcn(app, @PositionSwitchValueChanged, true);
            app.VelocitySwitchLabel = uilabel(app.UIFigure);
            app.VelocitySwitchLabel.Text = 'Velocity';
            app.VelocitySwitch = uiswitch(app.UIFigure, 'slider');
            app.VelocitySwitch.ValueChangedFcn = createCallbackFcn(app, @VelocitySwitchValueChanged, true);
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = '';
            app.RPMGauge = uigauge(app.UIFigure, 'circular');
            app.RPMGauge.Limits = [-240 240];
            app.RPMSwitch = uiswitch(app.UIFigure, 'slider');
            app.RPMSwitch.Items = {'RPM', 'mm'};
            app.RPMSwitch.ValueChangedFcn = createCallbackFcn(app, @RPMSwitchValueChanged, true);
            app.RPMSwitch.Value = 'RPM';
            app.SetRPMEditFieldLabel = uilabel(app.UIFigure);
            app.SetRPMEditFieldLabel.Text = 'Set RPM';
            app.SetRPMEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetRPMEditField.Limits = [0 250];
            app.SetRPMEditField.ValueChangedFcn = createCallbackFcn(app, @SetRPMEditFieldValueChanged, true);
            app.MotorsSwitchLabel = uilabel(app.UIFigure);
            app.MotorsSwitchLabel.Text = 'Motors';
            app.MotorsSwitch = uiswitch(app.UIFigure, 'rocker');
            app.MotorsSwitch.ValueChangedFcn = createCallbackFcn(app, @MotorsSwitchValueChanged, true);
            app.EmergencySTOPButton = uibutton(app.UIFigure, 'push');
            app.EmergencySTOPButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencySTOPButtonPushed, true);
            app.EmergencySTOPButton.Tooltip = {'Immediately breaks the motors to stop'};
            app.EmergencySTOPButton.Text = {'Emergency'; 'STOP'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.StatusLamp = uilamp(app.UIFigure);
            app.TareLocationButton = uibutton(app.UIFigure, 'push');
            app.TareLocationButton.ButtonPushedFcn = createCallbackFcn(app, @TareLocationButtonPushed, true);
            app.TareLocationButton.Text = 'Tare Location';
            app.DirectionKnobLabel = uilabel(app.UIFigure);
            app.DirectionKnobLabel.Text = 'Direction';
            app.DirectionKnob = uiknob(app.UIFigure, 'discrete');
            app.DirectionKnob.Items = {'Up', 'Stop', 'Down'};
            app.DirectionKnob.ValueChangedFcn = createCallbackFcn(app, @DirectionKnobValueChanged, true);
            app.DirectionKnob.Value = 'Stop';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.StatusBox = uitextarea(app.UIFigure);
            app.StatusBox.Editable = 'off';
            app.StatusBox.Value = {'Status: Not connected to UTM'};
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.SpeedLabel = uilabel(app.UIFigure);
            app.SpeedLabel.Text = 'Speed:';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.IncrementalMovePanel = uipanel(app.UIFigure);
            app.IncrementalMovePanel.Title = 'Incremental Move';
            app.MoveUpButton = uibutton(app.IncrementalMovePanel, 'push');
            app.MoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveUpButtonPushed, true);
            app.MoveUpButton.Text = 'Move Up';
            app.mmLabel_2 = uilabel(app.IncrementalMovePanel);
            app.mmLabel_2.Text = 'mm';
            app.Label_4 = uilabel(app.IncrementalMovePanel);
            app.Label_4.Text = '';
            app.MoveEditField = uieditfield(app.IncrementalMovePanel, 'numeric');
            app.MoveEditField.Value = 1.5;
            app.MoveDownButton = uibutton(app.IncrementalMovePanel, 'push');
            app.MoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveDownButtonPushed, true);
            app.MoveDownButton.Text = 'Move Down';
            app.StartcameraButton = uibutton(app.UIFigure, 'push');
            app.StartcameraButton.ButtonPushedFcn = createCallbackFcn(app, @StartcameraButtonPushed, true);
            app.StartcameraButton.Text = 'Start camera';
            app.StopcameraButton = uibutton(app.UIFigure, 'push');
            app.StopcameraButton.ButtonPushedFcn = createCallbackFcn(app, @StopcameraButtonPushed, true);
            app.StopcameraButton.Text = 'Stop camera';
            app.TareButton_2 = uibutton(app.UIFigure, 'push');
            app.TareButton_2.ButtonPushedFcn = createCallbackFcn(app, @TareButton_2Pushed, true);
            app.TareButton_2.Text = 'Tare';
            app.L_0EditFieldLabel = uilabel(app.UIFigure);
            app.L_0EditFieldLabel.Interpreter = 'tex';
            app.L_0EditFieldLabel.Text = 'L_0';
            app.L_0EditField = uieditfield(app.UIFigure, 'numeric');
            app.L_0EditField.Value = 65.12;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'FileName';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UTM_BAK

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end